classdef Battleship_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattleshipGUIUIFigure         matlab.ui.Figure
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        AttemptsRemainingEditFieldLabel  matlab.ui.control.Label
        AttemptsRemainingEditField    matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
        MissionStatusLabel            matlab.ui.control.Label
        ControlPanelLabel             matlab.ui.control.Label
        ImportantButtonsLabel         matlab.ui.control.Label
        SettingsLabel                 matlab.ui.control.Label
        NumberofShipsSpinnerLabel     matlab.ui.control.Label
        NumberofShipsSpinner          matlab.ui.control.Spinner
        TerminateButton               matlab.ui.control.Button
        HitLampLabel                  matlab.ui.control.Label
        HitLamp                       matlab.ui.control.Lamp
        NumberofGuessesSpinnerLabel   matlab.ui.control.Label
        NumberofGuessesSpinner        matlab.ui.control.Spinner
        CloseLampLabel                matlab.ui.control.Label
        CloseLamp                     matlab.ui.control.Lamp
        MissLampLabel                 matlab.ui.control.Label
        MissLamp                      matlab.ui.control.Lamp
    end

    
    properties (Access = public)
        game_start = 0 % game start flag
        
        ard = arduino % arduino object
        
        % pin layouts
        Adi_pins = ["D12", "D11", "D10";
                    "D8", "D7", "D6";
                    "D4", "D3", "D2"] % Adi's configuration
        
        Vincent_pins = ["D2", "D3", "D4";
                        "D7", "D8", "D9";
                        "D11", "D12", "D13"] % Vincent's configuration
        pins
        
        shipLocations % array of ship positions
        
        guessed % array of guessed locations
        
        position % coordinates of currently selected pin
        
        newPosition % coordinates of future pin
        
        valid_move = 1 % valid move flag
        
        % lightModes % array to store how LEDs behave when selected
        % unknown = (solid light)
        % ship = (no light)
        % nearby = (blink fast)
        % far = (blink slow)
        
        dirs = [0, 1; % right
            1, 0; % down
            -1, 0; % up
            0,-1]; % left
    end
    
    methods (Access = public)
        
%         function glow(app)
%             if app.game_start % if the game is still being played
%                 % decides how LEDs should behave when selected
%                 pin = app.pins(app.position(1), app.position(2));
%                 switch app.lightModes(app.position(1), app.position(2))
%                     case "?" % solid light
%                         writeDigitalPin(app.ard, pin, 1);
%                     case "s" % no light
%                         writeDigitalPin(app.ard, pin, 0);
%                     case "n" % blink fast
%                         while isequal(app.position, app.newPosition) && app.game_start % while position is not changing
%                             writeDigitalPin(app.ard, pin, 1);
%                             pause(0.1) % pause for 1/10 seconds
%                             writeDigitalPin(app.ard, pin, 0);
%                             pause(0.1) % pause for 1/10 seconds
%                         end
%                     case "f" % blink slow
%                         while isequal(app.position, app.newPosition) && app.game_start % while position is not changing
%                             writeDigitalPin(app.ard, pin, 1);
%                             pause(0.2) % pause for 1/5 seconds
%                             writeDigitalPin(app.ard, pin, 0);
%                             pause(0.2) % pause for 1/5 seconds
%                         end
%                 end
%                 clear pin
%             end
%         end
        
        function nearby = distance(app)
            nearby = 0;
            for i = 1:1:length(app.dirs) % cycle through directions
                % get position of LED we are checking
                x = app.position(1) + app.dirs(i, 1);
                y = app.position(2) + app.dirs(i, 2);
                % check for out of error bounds
                if x > 0 && x < 4 && y > 0 && y < 4
                    if app.shipLocations(x,y) == 1
                        nearby = 1;
                        break % no need to continue this code
                    end
                end
            end
        end
        
    end

    methods (Access = private)

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
                if app.game_start % if the game has started
                    uiresume % resumes program
                    if app.position(1) - 1 > 0 % if we can move up
                        app.valid_move = 1; % sets valid move flag to true
                    else
                        app.valid_move = 0; % we could not move up
                    end
                    if app.valid_move % if this is a valid move
                        app.newPosition = app.position + [-1, 0]; % store new position
                        pin = app.pins(app.position(1), app.position(2)); % get current pin
                        writeDigitalPin(app.ard, pin, 0); % erase current pin
                        app.position = app.newPosition; % update position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 1); % light up pin
                        % glow(app)
                        clear pin
                    end
                    uiwait % pauses program
                end
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
                if app.game_start % if the game has started
                    uiresume % resumes program
                    if app.position(2) + 1 < 4 % if we can move right
                        app.valid_move = 1; % sets valid move flag to true
                    else
                        app.valid_move = 0; % we could not move right
                    end
                    if app.valid_move % if we can move the pin there
                        app.newPosition = app.position + [0, 1]; % store new position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 0); % erase previous pin
                        app.position = app.newPosition; % update position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 1); % light up pin
                        % glow(app)
                        clear pin
                    end
                    uiwait % pauses program
                end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
                if app.game_start % if the game has started
                    uiresume % resumes program
                    if app.position(1) + 1 < 4 % if we can move down
                        app.valid_move = 1; % sets valid move flag to true
                    else
                        app.valid_move = 0; % we could not move down
                    end
                    if app.valid_move % if we can move the pin there
                        app.newPosition = app.position + [1, 0]; % store new position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 0); % erase previous pin
                        app.position = app.newPosition; % update position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 1); % light up pin
                        % glow(app) % lights up pin
                        clear pin
                    end
                    uiwait % pauses program
                end
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
                if app.game_start % if the game has started
                    uiresume % resumes program
                    if app.position(2) - 1 > 0 % if we can move right
                        app.valid_move = 1; % sets valid move flag to true
                    else
                        app.valid_move = 0; % we could not move right
                    end
                    if app.valid_move % if we can move the pin there
                        app.newPosition = app.position + [0, -1]; % store new position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 0); % erase previous pin
                        app.position = app.newPosition; % update position
                        pin = app.pins(app.position(1), app.position(2));
                        writeDigitalPin(app.ard, pin, 1); % light up pin
                        %glow(app) % lights up pin
                        clear pin
                    end
                    uiwait % pauses program
                end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                if ~app.game_start % if start flag has not been pushed yet
                    % set ship locations
                    app.shipLocations = zeros(3);
                    loc = randperm(9, app.NumberofShipsSpinner.Value);
                    app.shipLocations(loc) = 1;
                    clear loc
                    
                    % set pin configuration - ADI/VINCENT
                    app.pins = app.Adi_pins;
                    
                    % initialize guessed array
                    app.guessed = zeros(3);
                    
                    app.position = [2,2]; % coordinates of currently selected pin
        
                    app.newPosition = [2,2]; % coordinates of future pin
                    
                    % app.lightModes = repmat("?", 3); % array to store how LEDs behave when selected
        
                    % write LED position to board
                    writeDigitalPin(app.ard, app.pins(app.position(1), app.position(2)), 1);
                    
                    % intialize edit fields
                    app.ShipsRemainingEditField.Value = app.NumberofShipsSpinner.Value;
                    app.AttemptsRemainingEditField.Value = 5;
                    
                    app.game_start = 1; % set start flag to true
                end
                
                uiwait % pause program until another button is pressed
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
                if app.game_start % if the game has started
                    if ~app.guessed(app.position(1), app.position(2)) % if we have not guessed this before
                        uiresume % resumes program
                        app.AttemptsRemainingEditField.Value = app.AttemptsRemainingEditField.Value - 1; % decrement attempts remaining
                        app.guessed(app.position(1), app.position(2)) = 1; % remember the guess
                        if app.shipLocations(app.position(1), app.position(2)) == 1 % if there is a ship at guess
                            % app.lightModes(app.position(1), app.position(2)) = "s";
                            % decrement remaining ships
                            app.ShipsRemainingEditField.Value = app.ShipsRemainingEditField.Value - 1;
                            if app.ShipsRemainingEditField.Value == 0 % if we got all the ships
                                app.game_start = 0;
                                for i = 1:9
                                    writeDigitalPin(app.ard, app.pins(i), 0);
                                end
                                % play victory music
                            end
                        elseif app.AttemptsRemainingEditField.Value > 0 % if we have more attempts
                            %shipNearby = distance(app); % check for nearby ships
                            %if shipNearby
                            %    app.lightModes(app.position(1), app.position(2)) = "n";
                            %else
                            %    app.lightModes(app.position(1), app.position(2)) = "f";
                            %end
                            %glow(app) % lights up current pin
                            uiwait % pauses program
                        else % we are out of attempts but there are more ships remaining
                            app.game_start = 0;
                            % turn off all pins
                            for i = 1:9
                                writeDigitalPin(app.ard, app.pins(i), 0);
                            end
                            clear
                        end
                    end
                end
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
                app.game_start = 0; % set start flag to false
                % turn off all pins
                for i = 1:1:9
                    writeDigitalPin(app.ard, app.pins(i), 0);
                end
                clear % clear all objects
        end

        % Button pushed function: TerminateButton
        function TerminateButtonPushed(app, event)
            uiresume
            closereq
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BattleshipGUIUIFigure = uifigure;
            app.BattleshipGUIUIFigure.Name = 'Battleship GUI';
            app.UpButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.SelectButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ShipsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.AttemptsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.AttemptsRemainingEditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.StartButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Text = 'Quit';
            app.MissionStatusLabel = uilabel(app.BattleshipGUIUIFigure);
            app.MissionStatusLabel.Text = 'Mission Status';
            app.ControlPanelLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.ImportantButtonsLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ImportantButtonsLabel.Text = 'Important Buttons';
            app.SettingsLabel = uilabel(app.BattleshipGUIUIFigure);
            app.SettingsLabel.Text = 'Settings';
            app.NumberofShipsSpinnerLabel = uilabel(app.BattleshipGUIUIFigure);
            app.NumberofShipsSpinnerLabel.Text = 'Number of Ships';
            app.NumberofShipsSpinner = uispinner(app.BattleshipGUIUIFigure);
            app.NumberofShipsSpinner.Limits = [1 9];
            app.NumberofShipsSpinner.Editable = 'off';
            app.NumberofShipsSpinner.Value = 1;
            app.TerminateButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.TerminateButton.ButtonPushedFcn = createCallbackFcn(app, @TerminateButtonPushed, true);
            app.TerminateButton.Text = 'Terminate';
            app.HitLampLabel = uilabel(app.BattleshipGUIUIFigure);
            app.HitLampLabel.Text = 'Hit';
            app.HitLamp = uilamp(app.BattleshipGUIUIFigure);
            app.NumberofGuessesSpinnerLabel = uilabel(app.BattleshipGUIUIFigure);
            app.NumberofGuessesSpinnerLabel.Text = 'Number of Guesses';
            app.NumberofGuessesSpinner = uispinner(app.BattleshipGUIUIFigure);
            app.NumberofGuessesSpinner.Limits = [1 9];
            app.NumberofGuessesSpinner.Editable = 'off';
            app.NumberofGuessesSpinner.Value = 1;
            app.CloseLampLabel = uilabel(app.BattleshipGUIUIFigure);
            app.CloseLampLabel.Text = 'Close';
            app.CloseLamp = uilamp(app.BattleshipGUIUIFigure);
            app.MissLampLabel = uilabel(app.BattleshipGUIUIFigure);
            app.MissLampLabel.Text = 'Miss';
            app.MissLamp = uilamp(app.BattleshipGUIUIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BattleshipGUIUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattleshipGUIUIFigure)
        end
    end
end