classdef section1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlotButton                matlab.ui.control.Button
        FunctionDropDownLabel     matlab.ui.control.Label
        FunctionDropDown          matlab.ui.control.DropDown
        UIAxes                    matlab.ui.control.UIAxes
        AmplitudeEditFieldLabel   matlab.ui.control.Label
        AmplitudeEditField        matlab.ui.control.NumericEditField
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        XAxisLabelEditFieldLabel  matlab.ui.control.Label
        XAxisLabelEditField       matlab.ui.control.EditField
        YAxisLabelEditFieldLabel  matlab.ui.control.Label
        YAxisLabelEditField       matlab.ui.control.EditField
        FrequencyEditFieldLabel   matlab.ui.control.Label
        FrequencyEditField        matlab.ui.control.NumericEditField
        PhaseEditFieldLabel       matlab.ui.control.Label
        PhaseEditField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        functionstring
        x = 0:.1:(4*pi)
        amp
        freq
        phase
        title
        xlabel
        ylabel
        %functionstring = get(app.FunctionDropDown, 'Value')  % Description
    end


    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.functionstring = get(app.FunctionDropDown, 'Value')
            app.amp = get(app.AmplitudeEditField, 'Value')
            app.freq = get(app.FrequencyEditField, 'Value')
            app.phase = get(app.PhaseEditField, 'Value')
            app.title = get(app.TitleEditField, 'Value')
            app.xlabel = get(app.XAxisLabelEditField, 'Value')
            app.ylabel = get(app.YAxisLabelEditField, 'Value')
            
            %set(app.UIAxes, 'Title.String', app.title)
            %set(app.UIAxes, 'XLabel', app.xlabel)
            %set(app.UIAxes, 'YLabel', app.ylabel)
            app.UIAxes.Title.String = app.title
            app.UIAxes.XLabel.String = app.xlabel
            app.UIAxes.YLabel.String = app.ylabel
            
             if app.functionstring == 'sin'
                 plot(app.UIAxes, app.x, app.amp*sin(app.freq*app.x+app.phase))
             else app.functionstring == 'cos'
                 plot(app.UIAxes, app.x, app.amp*cos(app.freq*app.x+app.phase))
             end
              
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.FunctionDropDownLabel = uilabel(app.UIFigure);
            app.FunctionDropDownLabel.Text = 'Function';
            app.FunctionDropDown = uidropdown(app.UIFigure);
            app.FunctionDropDown.Items = {'sin', 'cos'};
            app.FunctionDropDown.Value = 'sin';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.AmplitudeEditFieldLabel = uilabel(app.UIFigure);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TitleEditFieldLabel = uilabel(app.UIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.XAxisLabelEditFieldLabel.Text = 'X-Axis Label';
            app.XAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisLabelEditFieldLabel = uilabel(app.UIFigure);
            app.YAxisLabelEditFieldLabel.Text = 'Y-Axis Label';
            app.YAxisLabelEditField = uieditfield(app.UIFigure, 'text');
            app.FrequencyEditFieldLabel = uilabel(app.UIFigure);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.PhaseEditFieldLabel = uilabel(app.UIFigure);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.UIFigure, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = section1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end